classdef octahedral_stress < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        INTERNLabel_3                matlab.ui.control.Label
        INTERNLabel_2                matlab.ui.control.Label
        INTERNLabel                  matlab.ui.control.Label
        OutputPanel                  matlab.ui.container.Panel
        Nmm2Label_3                  matlab.ui.control.Label
        Nmm2Label_2                  matlab.ui.control.Label
        Nmm2Label                    matlab.ui.control.Label
        EditField_5                  matlab.ui.control.NumericEditField
        EditField_4                  matlab.ui.control.NumericEditField
        EditField_3                  matlab.ui.control.NumericEditField
        EditField_2                  matlab.ui.control.NumericEditField
        EditField                    matlab.ui.control.NumericEditField
        OctahedralShearStressLabel   matlab.ui.control.Label
        OctahedralNormalStressLabel  matlab.ui.control.Label
        PrincipalStressesLabel       matlab.ui.control.Label
        ClearButton                  matlab.ui.control.Button
        CalculateButton              matlab.ui.control.Button
        StressTensorinNmm2Panel      matlab.ui.container.Panel
        zzEditField                  matlab.ui.control.NumericEditField
        zzEditFieldLabel             matlab.ui.control.Label
        zyEditField                  matlab.ui.control.NumericEditField
        zyEditFieldLabel             matlab.ui.control.Label
        zxEditField                  matlab.ui.control.NumericEditField
        zxEditFieldLabel             matlab.ui.control.Label
        yzEditField                  matlab.ui.control.NumericEditField
        yzEditFieldLabel             matlab.ui.control.Label
        yyEditField                  matlab.ui.control.NumericEditField
        yyEditFieldLabel             matlab.ui.control.Label
        yxEditField                  matlab.ui.control.NumericEditField
        yxEditFieldLabel             matlab.ui.control.Label
        xzEditField                  matlab.ui.control.NumericEditField
        xzEditFieldLabel             matlab.ui.control.Label
        xyEditField                  matlab.ui.control.NumericEditField
        xyEditFieldLabel             matlab.ui.control.Label
        xxEditField                  matlab.ui.control.NumericEditField
        xxEditFieldLabel             matlab.ui.control.Label
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
           
            cla(app.UIAxes);
            cla(app.UIAxes2);
            sigma_xx = app.xxEditField.Value;
            sigma_xy = app.xyEditField.Value;
            sigma_xz = app.xzEditField.Value;
            sigma_yx = app.yxEditField.Value;
            sigma_yy = app.yyEditField.Value;
            sigma_yz = app.yzEditField.Value;
            sigma_zx = app.zxEditField.Value;
            sigma_zy = app.zyEditField.Value;
            sigma_zz = app.zzEditField.Value;


sigma = [sigma_xx, sigma_xy, sigma_xz; sigma_yx, sigma_yy, sigma_yz; sigma_zx, sigma_zy, sigma_zz];

% Define the 8 vertices of the cube
v = [
    0, 0, 0;
    1, 0, 0;
    1, 1, 0;
    0, 1, 0;
    0, 0, 1;
    1, 0, 1;
    1, 1, 1;
    0, 1, 1
];

% Define the faces of the cuboid (each face is a set of 4 vertex indices)
faces = [
    1, 2, 6, 5;
    2, 3, 7, 6;
    3, 4, 8, 7;
    4, 1, 5, 8;
    1, 2, 3, 4;
    5, 6, 7, 8
];

% Define the origin of each face (for plotting the stress values)
origin = [(v(2,1) + v(3,1) + v(7,1) + v(6,1))/4, (v(2,2) + v(3,2) + v(7,2) + v(6,2))/4, (v(2,3) + v(3,3) + v(7,3) + v(6,3))/4;
          (v(1,1) + v(4,1) + v(8,1) + v(5,1))/4, (v(1,2) + v(4,2) + v(8,2) + v(5,2))/4, (v(1,3) + v(4,3) + v(8,3) + v(5,3))/4;
          (v(4,1) + v(3,1) + v(7,1) + v(8,1))/4, (v(4,2) + v(3,2) + v(7,2) + v(8,2))/4, (v(4,3) + v(3,3) + v(7,3) + v(8,3))/4;
          (v(1,1) + v(2,1) + v(6,1) + v(5,1))/4, (v(1,2) + v(2,2) + v(6,2) + v(5,2))/4, (v(1,3) + v(2,3) + v(6,3) + v(5,3))/4;
          (v(5,1) + v(6,1) + v(7,1) + v(8,1))/4, (v(5,2) + v(6,2) + v(7,2) + v(8,2))/4, (v(5,3) + v(6,3) + v(7,3) + v(8,3))/4;
          (v(1,1) + v(2,1) + v(3,1) + v(4,1))/4, (v(1,2) + v(2,2) + v(3,2) + v(4,2))/4, (v(1,3) + v(2,3) + v(3,3) + v(4,3))/4];


%Ploting the original cube
quiver3(app.UIAxes,origin(1,1), origin(1,2), origin(1,3), 1, 0, 0);
text(app.UIAxes,origin(1,1), origin(1,2), origin(1,3),'+(X-axis)', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
text(app.UIAxes,1 + origin(1,1), origin(1,2), origin(1,3), sprintf('\\sigma_{xx}: %.2f N/mm^2', sigma(1,1)));
hold(app.UIAxes,'on');
quiver3(app.UIAxes,origin(1,1), origin(1,2), origin(1,3), 0, 1, 0);
text(app.UIAxes,origin(1,1), 1 + origin(1,2), origin(1,3), sprintf('\\tau_{xy}: %.2f N/mm^2', sigma(1,2)));
hold(app.UIAxes,'on');
quiver3(app.UIAxes,origin(1,1), origin(1,2), origin(1,3), 0, 0, 1);
text(app.UIAxes,origin(1,1), origin(1,2), 1 + origin(1,3), sprintf('\\tau_{xz}: %.2f N/mm^2', sigma(1,3)));
hold(app.UIAxes,'on');
text(app.UIAxes,origin(2,1), origin(2,2), origin(2,3),'-(X-axis)','HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
quiver3(app.UIAxes,origin(2,1), origin(2,2), origin(2,3), -1, 0, 0);
text(app.UIAxes,-1 + origin(2,1), origin(2,2), origin(2,3), sprintf('\\sigma_{xx}: %.2f N/mm^2', sigma(1,1)));
hold(app.UIAxes,'on');
quiver3(app.UIAxes,origin(2,1), origin(2,2), origin(2,3), 0, -1, 0);
text(app.UIAxes,origin(2,1),-1 + origin(2,2), origin(2,3), sprintf('\\tau_{xy}: %.2f N/mm^2', sigma(1,2)));
hold(app.UIAxes,'on');
quiver3(app.UIAxes,origin(2,1), origin(2,2), origin(2,3), 0, 0, -1);
text(app.UIAxes,origin(2,1),origin(2,2),-1 + origin(2,3), sprintf('\\tau_{xz}: %.2f N/mm^2', sigma(1,3)));
hold(app.UIAxes,'on');
text(app.UIAxes,origin(3,1), origin(3,2), origin(3,3),'+(Y-axis)','HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
quiver3(app.UIAxes,origin(3,1), origin(3,2), origin(3,3), 1, 0, 0);
text(app.UIAxes,1 + origin(3,1), origin(3,2), origin(3,3), sprintf('\\tau_{yx}: %.2f N/mm^2', sigma(2,1)));
hold(app.UIAxes,'on');
quiver3(app.UIAxes,origin(3,1), origin(3,2), origin(3,3), 0, 1, 0);
text(app.UIAxes,origin(3,1), 1 + origin(3,2), origin(3,3), sprintf('\\sigma_{yy}: %.2f N/mm^2', sigma(2,2)));
hold(app.UIAxes,'on');
quiver3(app.UIAxes,origin(3,1), origin(3,2), origin(3,3), 0, 0, 1);
text(app.UIAxes,origin(3,1), origin(3,2), 1 + origin(3,3), sprintf('\\tau_{yz}: %.2f N/mm^2', sigma(2,3)));
hold(app.UIAxes,'on');
text(app.UIAxes,origin(4,1), origin(4,2), origin(4,3),'-(Y-axis)','HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
quiver3(app.UIAxes,origin(4,1), origin(4,2), origin(4,3), -1, 0, 0);
text(app.UIAxes,-1 + origin(4,1), origin(4,2), origin(4,3), sprintf('\\tau_{yx}: %.2f N/mm^2', sigma(2,1)));
hold(app.UIAxes,'on');
quiver3(app.UIAxes,origin(4,1), origin(4,2), origin(4,3), 0, -1, 0);
text(app.UIAxes,origin(4,1), -1 + origin(4,2), origin(4,3), sprintf('\\sigma_{yy}: %.2f N/mm^2', sigma(2,2)));
hold(app.UIAxes,'on');
quiver3(app.UIAxes,origin(4,1), origin(4,2), origin(4,3), 0, 0, -1);
text(app.UIAxes,origin(4,1), origin(4,2), -1 + origin(4,3), sprintf('\\tau_{yz}: %.2f N/mm^2', sigma(2,3)));
hold(app.UIAxes,'on');
text(app.UIAxes,origin(5,1), origin(5,2), origin(5,3),'+(Z-axis)','HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
quiver3(app.UIAxes,origin(5,1), origin(5,2), origin(5,3), 1, 0, 0);
text(app.UIAxes,1 + origin(5,1), origin(5,2), origin(5,3), sprintf('\\tau_{zx}: %.2f N/mm^2', sigma(3,1)));
hold(app.UIAxes,'on');
quiver3(app.UIAxes,origin(5,1), origin(5,2), origin(5,3), 0, 1, 0);
text(app.UIAxes,origin(5,1), 1 + origin(5,2), origin(5,3), sprintf('\\tau_{zy}: %.2f N/mm^2', sigma(3,2)));
hold(app.UIAxes,'on');
quiver3(app.UIAxes,origin(5,1), origin(5,2), origin(5,3), 0, 0, 1);
text(app.UIAxes,origin(5,1), origin(5,2), 1 + origin(5,3), sprintf('\\sigma_{zz}: %.2f N/mm^2', sigma(3,3)));
hold(app.UIAxes,'on');
text(app.UIAxes,origin(6,1), origin(6,2), origin(6,3),'-(Z-axis)','HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
quiver3(app.UIAxes,origin(6,1), origin(6,2), origin(6,3), -1, 0, 0);
text(app.UIAxes,-1 + origin(6,1), origin(6,2), origin(6,3), sprintf('\\tau_{zx}: %.2f N/mm^2', sigma(3,1)));
hold(app.UIAxes,'on');
quiver3(app.UIAxes,origin(6,1), origin(6,2), origin(6,3), 0, -1, 0);
text(app.UIAxes,origin(6,1), -1 + origin(6,2), origin(6,3), sprintf('\\tau_{zy}: %.2f N/mm^2', sigma(3,2)));
hold(app.UIAxes,'on');
quiver3(app.UIAxes,origin(6,1), origin(6,2), origin(6,3), 0, 0, -1);
text(app.UIAxes,origin(6,1), origin(6,2), -1 + origin(6,3), sprintf('\\sigma_{zz}: %.2f N/mm^2', sigma(3,3)));
patch(app.UIAxes,'Vertices', v, 'Faces', faces, 'FaceColor', 'r','FaceAlpha',0.5, 'EdgeColor', 'k');
axis(app.UIAxes,'equal');
xlabel(app.UIAxes,'X-axis');
ylabel(app.UIAxes,'Y-axis');
zlabel(app.UIAxes,'Z-axis');
title(app.UIAxes,'Element subjected to Normal & Shear Stresses');
grid(app.UIAxes,'on');
view(app.UIAxes,3);

% Compute the eigenvalues (principal stresses) and eigenvectors
[~, EigVal] = eig(sigma);

% Step 1: Calculate octahedral normal stress
sigma_o = (sigma(1,1) + sigma(2,2) + sigma(3,3)) / 3;

% Step 2: Calculate octahedral shear stress
tau_o = sqrt( (1/6) * ((sigma(1,1) - sigma(2,2))^2 + (sigma(2,2) - sigma(3,3))^2 + (sigma(3,3) - sigma(1,1))^2) + (1/2) * (sigma(1,2)^2 + sigma(1,3)^2 + sigma(2,3)^2) );

app.EditField.Value = EigVal(1,1);
app.EditField_2.Value = EigVal(2,2);
app.EditField_3.Value = EigVal(3,3);
app.EditField_4.Value = sigma_o;
app.EditField_5.Value = tau_o;

quiver3(app.UIAxes2,0, 0, 0, 1, 0, 0);
text(app.UIAxes2,1, 0, 0, sprintf('\\sigma_p: %.2f N/mm^2', EigVal(1,1)));
hold(app.UIAxes2,'on');
quiver3(app.UIAxes2,0, 0, 0, -1, 0, 0);
text(app.UIAxes2,-1, 0, 0, sprintf('\\sigma_p: %.2f N/mm^2', EigVal(1,1)));
hold(app.UIAxes2,'on');
quiver3(app.UIAxes2,0, 0, 0, 0, 1, 0);
text(app.UIAxes2,0, 1, 0, sprintf('\\sigma_p: %.2f N/mm^2', EigVal(2,2)));
hold(app.UIAxes2,'on');
quiver3(app.UIAxes2,0, 0, 0, 0, -1, 0);
text(app.UIAxes2,0, -1, 0, sprintf('\\sigma_p: %.2f N/mm^2', EigVal(2,2)));
hold(app.UIAxes2,'on');
quiver3(app.UIAxes2,0, 0, 0, 0, 0, 1);
text(app.UIAxes2,0, 0, 1, sprintf('\\sigma_p: %.2f N/mm^2', EigVal(3,3)));
hold(app.UIAxes2,'on');
quiver3(app.UIAxes2,0, 0, 0, 0, 0, -1);
text(app.UIAxes2,0, 0, -1, sprintf('\\sigma_p: %.2f N/mm^2', EigVal(3,3)));
hold(app.UIAxes2,'on');


% Define the vertices of the octahedron
V = [ 0.6, 0, 0;   % Vertex 1
     -0.6, 0, 0;   % Vertex 2
      0, 0.6, 0;   % Vertex 3
      0, -0.6, 0;   % Vertex 4
      0, 0, 0.6;   % Vertex 5
      0, 0, -0.6;];  % Vertex 6

% Define the faces using the vertex indices
F = [1, 3, 5;   % Face 1
     1, 3, 6;   % Face 2
     1, 4, 5;   % Face 3
     1, 4, 6;   % Face 4
     2, 3, 5;   % Face 5
     2, 3, 6;   % Face 6
     2, 4, 5;   % Face 7
     2, 4, 6];  % Face 8


centers = [(V(1,1)+V(3,1)+V(5,1))/3,(V(1,2)+V(3,2)+V(5,2))/3,(V(1,3)+V(3,3)+V(5,3))/3;
           (V(1,1)+V(3,1)+V(6,1))/3,(V(1,2)+V(3,2)+V(6,2))/3,(V(1,3)+V(3,3)+V(6,3))/3;
           (V(1,1)+V(4,1)+V(5,1))/3,(V(1,2)+V(4,2)+V(5,2))/3,(V(1,3)+V(4,3)+V(5,3))/3;
           (V(1,1)+V(4,1)+V(6,1))/3,(V(1,2)+V(4,2)+V(6,2))/3,(V(1,3)+V(4,3)+V(6,3))/3;
           (V(2,1)+V(3,1)+V(5,1))/3,(V(2,2)+V(3,2)+V(5,2))/3,(V(2,3)+V(3,3)+V(5,3))/3;
           (V(2,1)+V(3,1)+V(6,1))/3,(V(2,2)+V(3,2)+V(6,2))/3,(V(2,3)+V(3,3)+V(6,3))/3;
           (V(2,1)+V(4,1)+V(5,1))/3,(V(2,2)+V(4,2)+V(5,2))/3,(V(2,3)+V(4,3)+V(5,3))/3;
           (V(2,1)+V(4,1)+V(6,1))/3,(V(2,2)+V(4,2)+V(6,2))/3,(V(2,3)+V(4,3)+V(6,3))/3];


ac = V(3,:) - V(1,:);
ad = V(4,:) - V(1,:);
ae = V(5,:) - V(1,:);
af = V(6,:) - V(1,:);
bc = V(3,:) - V(2,:);
bd = V(4,:) - V(2,:);
be = V(5,:) - V(2,:);
bf = V(6,:) - V(2,:);

normal1 = cross(ac,ae);
normal1 = normal1 / norm(normal1);

normal2 = cross(ac,af);
normal2 = normal2 / norm(normal2);

normal3 = cross(ad,ae);
normal3 = normal3 / norm(normal3);

normal4 = cross(ad,af);
normal4 = normal4 / norm(normal4);

normal5 = cross(bc,be);
normal5 = normal5 / norm(normal5);

normal6 = cross(bc,bf);
normal6 = normal6 / norm(normal6);

normal7 = cross(bd,be);
normal7 = normal7 / norm(normal7);

normal8 = cross(bd,bf);
normal8 = normal8 / norm(normal8);


quiver3(app.UIAxes2,centers(1,1),centers(1,2),centers(1,3),normal1(1),normal1(2),normal1(3));
text(app.UIAxes2,centers(1,1) + normal1(1),centers(1,2) + normal1(2),centers(1,3) + normal1(3),sprintf('\\sigma_{oct}: %.2f N/mm^2',sigma_o));
hold(app.UIAxes2,'on');

quiver3(app.UIAxes2,centers(2,1),centers(2,2),centers(2,3),-normal2(1),-normal2(2),-normal2(3));
text(app.UIAxes2,centers(2,1) - normal2(1),centers(2,2) - normal2(2),centers(2,3) - normal2(3),sprintf('\\sigma_{oct}: %.2f N/mm^2',sigma_o));
hold(app.UIAxes2,'on');

quiver3(app.UIAxes2,centers(3,1),centers(3,2),centers(3,3),-normal3(1),-normal3(2),-normal3(3));
text(app.UIAxes2,centers(3,1) - normal3(1),centers(3,2) - normal3(2),centers(3,3) - normal3(3),sprintf('\\sigma_{oct}: %.2f N/mm^2',sigma_o));
hold(app.UIAxes2,'on');

quiver3(app.UIAxes2,centers(4,1),centers(4,2),centers(4,3),normal4(1),normal4(2),normal4(3));
text(app.UIAxes2,centers(4,1) + normal4(1),centers(4,2) + normal4(2),centers(4,3) + normal4(3),sprintf('\\sigma_{oct}: %.2f N/mm^2',sigma_o));
hold(app.UIAxes2,'on');

quiver3(app.UIAxes2,centers(5,1),centers(5,2),centers(5,3),-normal5(1),-normal5(2),-normal5(3));
text(app.UIAxes2,centers(5,1) - normal5(1),centers(5,2) - normal5(2),centers(5,3) - normal5(3),sprintf('\\sigma_{oct}: %.2f N/mm^2',sigma_o));
hold(app.UIAxes2,'on');

quiver3(app.UIAxes2,centers(6,1),centers(6,2),centers(6,3),normal6(1),normal6(2),normal6(3));
text(app.UIAxes2,centers(6,1) + normal6(1),centers(6,2) + normal6(2),centers(6,3) + normal6(3),sprintf('\\sigma_{oct}: %.2f N/mm^2',sigma_o));
hold(app.UIAxes2,'on');

quiver3(app.UIAxes2,centers(7,1),centers(7,2),centers(7,3),normal7(1),normal7(2),normal7(3));
text(app.UIAxes2,centers(7,1) + normal7(1),centers(7,2) + normal7(2),centers(7,3) + normal7(3),sprintf('\\sigma_{oct}: %.2f N/mm^2',sigma_o));
hold(app.UIAxes2,'on');

quiver3(app.UIAxes2,centers(8,1),centers(8,2),centers(8,3),-normal8(1),-normal8(2),-normal8(3));
text(app.UIAxes2,centers(8,1) - normal8(1),centers(8,2) - normal8(2),centers(8,3) - normal8(3),sprintf('\\sigma_{oct}: %.2f N/mm^2',sigma_o));
hold(app.UIAxes2,'on');

% Define direction vectors towards vertices 5 and 6 from each face center
quiver3(app.UIAxes2,centers(1,1), centers(1,2), centers(1,3), centers(1,1) - V(5,1), centers(1,2) - V(5,2), centers(1,3) - V(5,3));
text(app.UIAxes2,2*centers(1,1) - V(5,1), 2*centers(1,2) - V(5,2), 2*centers(1,3) - V(5,3),sprintf('\\tau_{oct}: %.2f N/mm^2',tau_o));
hold(app.UIAxes2,'on');

quiver3(app.UIAxes2,centers(2,1), centers(2,2), centers(2,3), centers(2,1) - V(6,1), centers(2,2) - V(6,2), centers(2,3) - V(6,3));
text(app.UIAxes2,2*centers(2,1) - V(6,1), 2*centers(2,2) - V(6,2), 2*centers(2,3) - V(6,3),sprintf('\\tau_{oct}: %.2f N/mm^2',tau_o));
hold(app.UIAxes2,'on');

quiver3(app.UIAxes2,centers(3,1), centers(3,2), centers(3,3), centers(3,1) - V(5,1), centers(3,2) - V(5,2), centers(3,3) - V(5,3));
text(app.UIAxes2,2*centers(3,1) - V(5,1), 2*centers(3,2) - V(5,2), 2*centers(3,3) - V(5,3),sprintf('\\tau_{oct}: %.2f N/mm^2',tau_o));
hold(app.UIAxes2,'on');

quiver3(app.UIAxes2,centers(4,1), centers(4,2), centers(4,3), centers(4,1) - V(6,1), centers(4,2) - V(6,2), centers(4,3) - V(6,3));
text(app.UIAxes2,2*centers(4,1) - V(6,1), 2*centers(4,2) - V(6,2), 2*centers(4,3) - V(6,3),sprintf('\\tau_{oct}: %.2f N/mm^2',tau_o));
hold(app.UIAxes2,'on');

quiver3(app.UIAxes2,centers(5,1), centers(5,2), centers(5,3), centers(5,1) - V(5,1), centers(5,2) - V(5,2), centers(5,3) - V(5,3));
text(app.UIAxes2,2*centers(5,1) - V(5,1), 2*centers(5,2) - V(5,2), 2*centers(5,3) - V(5,3),sprintf('\\tau_{oct}: %.2f N/mm^2',tau_o));
hold(app.UIAxes2,'on');

quiver3(app.UIAxes2,centers(6,1), centers(6,2), centers(6,3), centers(6,1) - V(6,1), centers(6,2) - V(6,2), centers(6,3) - V(6,3));
text(app.UIAxes2,2*centers(6,1) - V(6,1), 2*centers(6,2) - V(6,2), 2*centers(6,3) - V(6,3),sprintf('\\tau_{oct}: %.2f N/mm^2',tau_o));
hold(app.UIAxes2,'on');

quiver3(app.UIAxes2,centers(7,1), centers(7,2), centers(7,3), centers(7,1) - V(5,1), centers(7,2) - V(5,2), centers(7,3) - V(5,3));
text(app.UIAxes2,2*centers(7,1) - V(5,1), 2*centers(7,2) - V(5,2), 2*centers(7,3) - V(5,3),sprintf('\\tau_{oct}: %.2f N/mm^2',tau_o));
hold(app.UIAxes2,'on');

quiver3(app.UIAxes2,centers(8,1), centers(8,2), centers(8,3), centers(8,1) - V(6,1), centers(8,2) - V(6,2), centers(8,3) - V(6,3));
text(app.UIAxes2,2*centers(8,1) - V(6,1), 2*centers(8,2) - V(6,2), 2*centers(8,3) - V(6,3),sprintf('\\tau_{oct}: %.2f N/mm^2',tau_o));
patch(app.UIAxes2,'Vertices', V, 'Faces', F, 'FaceColor', 'cyan', 'FaceAlpha',0.6,'EdgeColor', 'black');
axis(app.UIAxes2,'equal');
xlabel(app.UIAxes2,'X-axis');
ylabel(app.UIAxes2,'Y-axis');
zlabel(app.UIAxes2,'Z-axis');
title(app.UIAxes2,'Octahedral Normal and Shear stress');
grid(app.UIAxes2,'on');
view(app.UIAxes2,20,12.5);

        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.EditField.Value = 0;
            app.EditField_2.Value = 0;
            app.EditField_3.Value = 0;
            app.EditField_4.Value = 0;
            app.EditField_5.Value = 0;
            cla(app.UIAxes);
            cla(app.UIAxes2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Element subjected to Normal & Shear Stresses')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Octahedral Stresses and Planes')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.StressTensorinNmm2Panel = uipanel(app.UIFigure);
            app.StressTensorinNmm2Panel.Title = 'Stress Tensor ( in N/mm^2)';
            app.xxEditFieldLabel = uilabel(app.StressTensorinNmm2Panel);
            app.xxEditFieldLabel.Text = 'σxx';
            app.xxEditField = uieditfield(app.StressTensorinNmm2Panel, 'numeric');
            app.xyEditFieldLabel = uilabel(app.StressTensorinNmm2Panel);
            app.xyEditFieldLabel.Text = 'σxy';
            app.xyEditField = uieditfield(app.StressTensorinNmm2Panel, 'numeric');
            app.xzEditFieldLabel = uilabel(app.StressTensorinNmm2Panel);
            app.xzEditFieldLabel.Text = 'σxz';
            app.xzEditField = uieditfield(app.StressTensorinNmm2Panel, 'numeric');
            app.yxEditFieldLabel = uilabel(app.StressTensorinNmm2Panel);
            app.yxEditFieldLabel.Text = 'σyx';
            app.yxEditField = uieditfield(app.StressTensorinNmm2Panel, 'numeric');
            app.yyEditFieldLabel = uilabel(app.StressTensorinNmm2Panel);
            app.yyEditFieldLabel.Text = 'σyy';
            app.yyEditField = uieditfield(app.StressTensorinNmm2Panel, 'numeric');
            app.yzEditFieldLabel = uilabel(app.StressTensorinNmm2Panel);
            app.yzEditFieldLabel.Text = 'σyz';
            app.yzEditField = uieditfield(app.StressTensorinNmm2Panel, 'numeric');
            app.zxEditFieldLabel = uilabel(app.StressTensorinNmm2Panel);
            app.zxEditFieldLabel.Text = 'σzx';
            app.zxEditField = uieditfield(app.StressTensorinNmm2Panel, 'numeric');
            app.zyEditFieldLabel = uilabel(app.StressTensorinNmm2Panel);
            app.zyEditFieldLabel.Text = 'σzy';
            app.zyEditField = uieditfield(app.StressTensorinNmm2Panel, 'numeric');
            app.zzEditFieldLabel = uilabel(app.StressTensorinNmm2Panel);
            app.zzEditFieldLabel.Text = 'σzz';
            app.zzEditField = uieditfield(app.StressTensorinNmm2Panel, 'numeric');
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.OutputPanel = uipanel(app.UIFigure);
            app.OutputPanel.Title = 'Output';
            app.PrincipalStressesLabel = uilabel(app.OutputPanel);
            app.PrincipalStressesLabel.Text = 'Principal Stresses';
            app.OctahedralNormalStressLabel = uilabel(app.OutputPanel);
            app.OctahedralNormalStressLabel.Text = 'Octahedral Normal Stress';
            app.OctahedralShearStressLabel = uilabel(app.OutputPanel);
            app.OctahedralShearStressLabel.Text = 'Octahedral Shear Stress';
            app.EditField = uieditfield(app.OutputPanel, 'numeric');
            app.EditField_2 = uieditfield(app.OutputPanel, 'numeric');
            app.EditField_3 = uieditfield(app.OutputPanel, 'numeric');
            app.EditField_4 = uieditfield(app.OutputPanel, 'numeric');
            app.EditField_5 = uieditfield(app.OutputPanel, 'numeric');
            app.Nmm2Label = uilabel(app.OutputPanel);
            app.Nmm2Label.Text = 'N/mm^2';
            app.Nmm2Label_2 = uilabel(app.OutputPanel);
            app.Nmm2Label_2.Text = 'N/mm^2';
            app.Nmm2Label_3 = uilabel(app.OutputPanel);
            app.Nmm2Label_3.Text = 'N/mm^2';
            app.INTERNLabel = uilabel(app.UIFigure);
            app.INTERNLabel.Text = {'INTERNATIONAL INSTITUTE OF INFORMATION TECHNOLOGY, HYDERABAD'; 'EARTHQUAKE ENGINEERING RESEARCH CENTRE'; 'PROJECT'; 'THEORY OF ELASTICITY (CE 0.501)'};
            app.INTERNLabel_2 = uilabel(app.UIFigure);
            app.INTERNLabel_2.Text = 'OCTAHEDRAL NORMAL AND SHEAR STRESSES';
            app.INTERNLabel_3 = uilabel(app.UIFigure);
            app.INTERNLabel_3.Text = 'Presented by : Jayant Lodhi (2024210009)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = octahedral_stress

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end