classdef Principal_Stress_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ElementButton               matlab.ui.control.Button
        AnalysisButton              matlab.ui.control.Button
        ClearButton                 matlab.ui.control.Button
        INTERNLabel_3               matlab.ui.control.Label
        INTERNLabel_2               matlab.ui.control.Label
        INTERNLabel                 matlab.ui.control.Label
        OutputsPanel                matlab.ui.container.Panel
        rdPrincipalStressNmm2anditsDirectionPanel  matlab.ui.container.Panel
        DirectionLabel_3            matlab.ui.control.Label
        EditField4_9                matlab.ui.control.NumericEditField
        EditField4_8                matlab.ui.control.NumericEditField
        EditField4_7                matlab.ui.control.NumericEditField
        rdPrincipalStressEditField  matlab.ui.control.NumericEditField
        rdPrincipalStressEditFieldLabel  matlab.ui.control.Label
        ndPrincipalStressNmm2anditsDirectionPanel  matlab.ui.container.Panel
        DirectionLabel_2            matlab.ui.control.Label
        EditField4_6                matlab.ui.control.NumericEditField
        EditField4_5                matlab.ui.control.NumericEditField
        EditField4_4                matlab.ui.control.NumericEditField
        ndPrincipalStressEditField  matlab.ui.control.NumericEditField
        ndPrincipalStressEditFieldLabel  matlab.ui.control.Label
        stPrincipalStressNmm2anditsDirectionPanel  matlab.ui.container.Panel
        DirectionLabel              matlab.ui.control.Label
        EditField4_3                matlab.ui.control.NumericEditField
        EditField4_2                matlab.ui.control.NumericEditField
        EditField4                  matlab.ui.control.NumericEditField
        stPrincipalStressEditField  matlab.ui.control.NumericEditField
        stPrincipalStressEditFieldLabel  matlab.ui.control.Label
        StressTensorinNmm2Panel     matlab.ui.container.Panel
        zzEditField                 matlab.ui.control.NumericEditField
        zzEditFieldLabel            matlab.ui.control.Label
        zyEditField                 matlab.ui.control.NumericEditField
        zyEditFieldLabel            matlab.ui.control.Label
        zxEditField                 matlab.ui.control.NumericEditField
        zxEditFieldLabel            matlab.ui.control.Label
        yzEditField                 matlab.ui.control.NumericEditField
        yzEditFieldLabel            matlab.ui.control.Label
        yyEditField                 matlab.ui.control.NumericEditField
        yyEditFieldLabel            matlab.ui.control.Label
        yxEditField                 matlab.ui.control.NumericEditField
        yxEditFieldLabel            matlab.ui.control.Label
        xzEditField                 matlab.ui.control.NumericEditField
        xzEditFieldLabel            matlab.ui.control.Label
        xyEditField                 matlab.ui.control.NumericEditField
        xyEditFieldLabel            matlab.ui.control.Label
        xxEditField                 matlab.ui.control.NumericEditField
        Label                       matlab.ui.control.Label
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AnalysisButton
        function AnalysisButtonPushed(app, event)
            
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            sigma_xx = app.xxEditField.Value;
            sigma_xy = app.xyEditField.Value;
            sigma_xz = app.xzEditField.Value;
            sigma_yx = app.yxEditField.Value;
            sigma_yy = app.yyEditField.Value;
            sigma_yz = app.yzEditField.Value;
            sigma_zx = app.zxEditField.Value;
            sigma_zy = app.zyEditField.Value;
            sigma_zz = app.zzEditField.Value;


sigma = [sigma_xx, sigma_xy, sigma_xz; sigma_yx, sigma_yy, sigma_yz; sigma_zx, sigma_zy, sigma_zz];

% Compute the eigenvalues (principal stresses) and eigenvectors
[EigVec, EigVal] = eig(sigma);

% Define the 8 vertices of the cube
v = [
    0, 0, 0;
    1, 0, 0;
    1, 1, 0;
    0, 1, 0;
    0, 0, 1;
    1, 0, 1;
    1, 1, 1;
    0, 1, 1
];

% Find the center of the cube
center = mean(v, 1);  % Take the average of all vertices

% Define the faces of the cuboid (each face is a set of 4 vertex indices)
faces = [
    1, 2, 6, 5;
    2, 3, 7, 6;
    3, 4, 8, 7;
    4, 1, 5, 8;
    1, 2, 3, 4;
    5, 6, 7, 8
];

% Translate the cube so that the center is at the origin
v_centered = v - center;

% Apply the rotation matrix to the centered cube vertices
r_v_centered = (EigVec * v_centered')';

% Translate the cube back to its original position
r_v = r_v_centered + center;

% Define the origin of each face (for plotting the stress values)
origin = [(v(2,1) + v(3,1) + v(7,1) + v(6,1))/4, (v(2,2) + v(3,2) + v(7,2) + v(6,2))/4, (v(2,3) + v(3,3) + v(7,3) + v(6,3))/4;
          (v(1,1) + v(4,1) + v(8,1) + v(5,1))/4, (v(1,2) + v(4,2) + v(8,2) + v(5,2))/4, (v(1,3) + v(4,3) + v(8,3) + v(5,3))/4;
          (v(4,1) + v(3,1) + v(7,1) + v(8,1))/4, (v(4,2) + v(3,2) + v(7,2) + v(8,2))/4, (v(4,3) + v(3,3) + v(7,3) + v(8,3))/4;
          (v(1,1) + v(2,1) + v(6,1) + v(5,1))/4, (v(1,2) + v(2,2) + v(6,2) + v(5,2))/4, (v(1,3) + v(2,3) + v(6,3) + v(5,3))/4;
          (v(5,1) + v(6,1) + v(7,1) + v(8,1))/4, (v(5,2) + v(6,2) + v(7,2) + v(8,2))/4, (v(5,3) + v(6,3) + v(7,3) + v(8,3))/4;
          (v(1,1) + v(2,1) + v(3,1) + v(4,1))/4, (v(1,2) + v(2,2) + v(3,2) + v(4,2))/4, (v(1,3) + v(2,3) + v(3,3) + v(4,3))/4];

% Apply the same translation for the rotated vertices
origin_r = [(r_v(2,1) + r_v(3,1) + r_v(7,1) + r_v(6,1))/4, (r_v(2,2) + r_v(3,2) + r_v(7,2) + r_v(6,2))/4, (r_v(2,3) + r_v(3,3) + r_v(7,3) + r_v(6,3))/4;
            (r_v(1,1) + r_v(4,1) + r_v(8,1) + r_v(5,1))/4, (r_v(1,2) + r_v(4,2) + r_v(8,2) + r_v(5,2))/4, (r_v(1,3) + r_v(4,3) + r_v(8,3) + r_v(5,3))/4;
            (r_v(4,1) + r_v(3,1) + r_v(7,1) + r_v(8,1))/4, (r_v(4,2) + r_v(3,2) + r_v(7,2) + r_v(8,2))/4, (r_v(4,3) + r_v(3,3) + r_v(7,3) + r_v(8,3))/4;
            (r_v(1,1) + r_v(2,1) + r_v(6,1) + r_v(5,1))/4, (r_v(1,2) + r_v(2,2) + r_v(6,2) + r_v(5,2))/4, (r_v(1,3) + r_v(2,3) + r_v(6,3) + r_v(5,3))/4;
            (r_v(5,1) + r_v(6,1) + r_v(7,1) + r_v(8,1))/4, (r_v(5,2) + r_v(6,2) + r_v(7,2) + r_v(8,2))/4, (r_v(5,3) + r_v(6,3) + r_v(7,3) + r_v(8,3))/4;
            (r_v(1,1) + r_v(2,1) + r_v(3,1) + r_v(4,1))/4, (r_v(1,2) + r_v(2,2) + r_v(3,2) + r_v(4,2))/4, (r_v(1,3) + r_v(2,3) + r_v(3,3) + r_v(4,3))/4];


%Ploting the original cube
quiver3(app.UIAxes2,origin(1,1), origin(1,2), origin(1,3), 1, 0, 0);
text(app.UIAxes2,origin(1,1), origin(1,2), origin(1,3),'+(X-axis)', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
text(app.UIAxes2,1 + origin(1,1), origin(1,2), origin(1,3), sprintf('\\sigma_{xx}: %.2f N/mm^2', sigma(1,1)));
hold(app.UIAxes2,'on');
quiver3(app.UIAxes2,origin(1,1), origin(1,2), origin(1,3), 0, 1, 0);
text(app.UIAxes2,origin(1,1), 1 + origin(1,2), origin(1,3), sprintf('\\tau_{xy}: %.2f N/mm^2', sigma(1,2)));
hold(app.UIAxes2,'on');
quiver3(app.UIAxes2,origin(1,1), origin(1,2), origin(1,3), 0, 0, 1);
text(app.UIAxes2,origin(1,1), origin(1,2), 1 + origin(1,3), sprintf('\\tau_{xz}: %.2f N/mm^2', sigma(1,3)));
hold(app.UIAxes2,'on');
text(app.UIAxes2,origin(2,1), origin(2,2), origin(2,3),'-(X-axis)','HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
quiver3(app.UIAxes2,origin(2,1), origin(2,2), origin(2,3), -1, 0, 0);
text(app.UIAxes2,-1 + origin(2,1), origin(2,2), origin(2,3), sprintf('\\sigma_{xx}: %.2f N/mm^2', sigma(1,1)));
hold(app.UIAxes2,'on');
quiver3(app.UIAxes2,origin(2,1), origin(2,2), origin(2,3), 0, -1, 0);
text(app.UIAxes2,origin(2,1),-1 + origin(2,2), origin(2,3), sprintf('\\tau_{xy}: %.2f N/mm^2', sigma(1,2)));
hold(app.UIAxes2,'on');
quiver3(app.UIAxes2,origin(2,1), origin(2,2), origin(2,3), 0, 0, -1);
text(app.UIAxes2,origin(2,1),origin(2,2),-1 + origin(2,3), sprintf('\\tau_{xz}: %.2f N/mm^2', sigma(1,3)));
hold(app.UIAxes2,'on');
text(app.UIAxes2,origin(3,1), origin(3,2), origin(3,3),'+(Y-axis)','HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
quiver3(app.UIAxes2,origin(3,1), origin(3,2), origin(3,3), 1, 0, 0);
text(app.UIAxes2,1 + origin(3,1), origin(3,2), origin(3,3), sprintf('\\tau_{yx}: %.2f N/mm^2', sigma(2,1)));
hold(app.UIAxes2,'on');
quiver3(app.UIAxes2,origin(3,1), origin(3,2), origin(3,3), 0, 1, 0);
text(app.UIAxes2,origin(3,1), 1 + origin(3,2), origin(3,3), sprintf('\\sigma_{yy}: %.2f N/mm^2', sigma(2,2)));
hold(app.UIAxes2,'on');
quiver3(app.UIAxes2,origin(3,1), origin(3,2), origin(3,3), 0, 0, 1);
text(app.UIAxes2,origin(3,1), origin(3,2), 1 + origin(3,3), sprintf('\\tau_{yz}: %.2f N/mm^2', sigma(2,3)));
hold(app.UIAxes2,'on');
text(app.UIAxes2,origin(4,1), origin(4,2), origin(4,3),'-(Y-axis)','HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
quiver3(app.UIAxes2,origin(4,1), origin(4,2), origin(4,3), -1, 0, 0);
text(app.UIAxes2,-1 + origin(4,1), origin(4,2), origin(4,3), sprintf('\\tau_{yx}: %.2f N/mm^2', sigma(2,1)));
hold(app.UIAxes2,'on');
quiver3(app.UIAxes2,origin(4,1), origin(4,2), origin(4,3), 0, -1, 0);
text(app.UIAxes2,origin(4,1), -1 + origin(4,2), origin(4,3), sprintf('\\sigma_{yy}: %.2f N/mm^2', sigma(2,2)));
hold(app.UIAxes2,'on');
quiver3(app.UIAxes2,origin(4,1), origin(4,2), origin(4,3), 0, 0, -1);
text(app.UIAxes2,origin(4,1), origin(4,2), -1 + origin(4,3), sprintf('\\tau_{yz}: %.2f N/mm^2', sigma(2,3)));
hold(app.UIAxes2,'on');
text(app.UIAxes2,origin(5,1), origin(5,2), origin(5,3),'+(Z-axis)','HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
quiver3(app.UIAxes2,origin(5,1), origin(5,2), origin(5,3), 1, 0, 0);
text(app.UIAxes2,1 + origin(5,1), origin(5,2), origin(5,3), sprintf('\\tau_{zx}: %.2f N/mm^2', sigma(3,1)));
hold(app.UIAxes2,'on');
quiver3(app.UIAxes2,origin(5,1), origin(5,2), origin(5,3), 0, 1, 0);
text(app.UIAxes2,origin(5,1), 1 + origin(5,2), origin(5,3), sprintf('\\tau_{zy}: %.2f N/mm^2', sigma(3,2)));
hold(app.UIAxes2,'on');
quiver3(app.UIAxes2,origin(5,1), origin(5,2), origin(5,3), 0, 0, 1);
text(app.UIAxes2,origin(5,1), origin(5,2), 1 + origin(5,3), sprintf('\\sigma_{zz}: %.2f N/mm^2', sigma(3,3)));
hold(app.UIAxes2,'on');
text(app.UIAxes2,origin(6,1), origin(6,2), origin(6,3),'-(Z-axis)','HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
quiver3(app.UIAxes2,origin(6,1), origin(6,2), origin(6,3), -1, 0, 0);
text(app.UIAxes2,-1 + origin(6,1), origin(6,2), origin(6,3), sprintf('\\tau_{zx}: %.2f N/mm^2', sigma(3,1)));
hold(app.UIAxes2,'on');
quiver3(app.UIAxes2,origin(6,1), origin(6,2), origin(6,3), 0, -1, 0);
text(app.UIAxes2,origin(6,1), -1 + origin(6,2), origin(6,3), sprintf('\\tau_{zy}: %.2f N/mm^2', sigma(3,2)));
hold(app.UIAxes2,'on');
quiver3(app.UIAxes2,origin(6,1), origin(6,2), origin(6,3), 0, 0, -1);
text(app.UIAxes2,origin(6,1), origin(6,2), -1 + origin(6,3), sprintf('\\sigma_{zz}: %.2f N/mm^2', sigma(3,3)));
patch(app.UIAxes2,'Vertices', v, 'Faces', faces, 'FaceColor', 'r','FaceAlpha',0.5, 'EdgeColor', 'k');
axis(app.UIAxes2,'equal');
xlabel(app.UIAxes2,'X');
ylabel(app.UIAxes2,'Y');
zlabel(app.UIAxes2,'Z');
title(app.UIAxes2,'Element subjected to Normal & Shear Stresses');
grid(app.UIAxes2,'on');
view(app.UIAxes2,3);


% Define the number of steps for the animation
nSteps = 100;

% Plot the cube for the animation
h = patch(app.UIAxes,'Vertices', v, 'Faces', faces, 'FaceColor', 'g', 'FaceAlpha', 0.5, 'EdgeColor', 'k');
axis(app.UIAxes,'equal');
xlabel(app.UIAxes,'X');
ylabel(app.UIAxes,'Y');
zlabel(app.UIAxes,'Z');
title(app.UIAxes,'Element rotating to Principal Plane');
grid (app.UIAxes,'on');
view(app.UIAxes,3);

% Create text labels for the axes and store the handles in a cell array
textHandles = cell(1, 6);

textHandles{1} = text(app.UIAxes,origin(1,1), origin(1,2), origin(1,3), '+(X-axis)','HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
textHandles{2} = text(app.UIAxes,origin(2,1), origin(2,2), origin(2,3), '-(X-axis)','HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
textHandles{3} = text(app.UIAxes,origin(3,1), origin(3,2), origin(3,3), '+(Y-axis)','HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
textHandles{4} = text(app.UIAxes,origin(4,1), origin(4,2), origin(4,3), '-(Y-axis)','HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
textHandles{5} = text(app.UIAxes,origin(5,1), origin(5,2), origin(5,3), '+(Z-axis)','HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
textHandles{6} = text(app.UIAxes,origin(6,1), origin(6,2), origin(6,3), '-(Z-axis)','HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');

% Rotation angles
theta = linspace(0, pi/2, nSteps);  % Adjust this range for the desired rotation speed

% Animate the rotation from the original orientation to the rotated orientation
for t = 1:nSteps
    % Interpolate between the original and rotated cubes
    alpha = t / nSteps;
    current_v = (1 - alpha) * v + alpha * r_v;
    
    % Update the cube vertices
    h.Vertices = current_v;
    
    % Update the text position based on interpolation
    for i = 1:6
        % Interpolate the positions of the text labels as well
        new_pos = (1 - alpha) * origin(i,:) + alpha * origin_r(i,:);
        textHandles{i}.Position = new_pos;  % Update text position
    end
    
    % Pause to create the animation effect
    pause(0.05);
end

% getting values of Principal Stresses and its directions
app.stPrincipalStressEditField.Value = EigVal(1,1);
app.EditField4.Value = EigVec(1,1);
app.EditField4_2.Value = EigVec(2,1);
app.EditField4_3.Value = EigVec(3,1);

app.ndPrincipalStressEditField.Value = EigVal(2,2);
app.EditField4_4.Value = EigVec(1,2);
app.EditField4_5.Value = EigVec(2,2);
app.EditField4_6.Value = EigVec(3,2);

app.rdPrincipalStressEditField.Value = EigVal(3,3);
app.EditField4_7.Value = EigVec(1,3);
app.EditField4_8.Value = EigVec(2,3);
app.EditField4_9.Value = EigVec(3,3);


%Ploting the rotated cube
quiver3(app.UIAxes3,origin_r(1,1), origin_r(1,2), origin_r(1,3), EigVec(1,1), EigVec(2,1), EigVec(3,1));
text(app.UIAxes3,origin_r(1,1) + EigVec(1,1), origin_r(1,2) + EigVec(2,1), origin_r(1,3) + EigVec(3,1), sprintf('\\sigma_p: %.2f N/mm^2', EigVal(1,1)));
text(app.UIAxes3,origin_r(1,1), origin_r(1,2), origin_r(1,3),'+(X-axis)', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
hold(app.UIAxes3,'on');
quiver3(app.UIAxes3,origin_r(2,1), origin_r(2,2), origin_r(2,3), -EigVec(1,1), -EigVec(2,1), -EigVec(3,1));
text(app.UIAxes3,origin_r(2,1) - EigVec(1,1), origin_r(2,2) - EigVec(2,1), origin_r(2,3) - EigVec(3,1), sprintf('\\sigma_p: %.2f N/mm^2', EigVal(1,1)));
text(app.UIAxes3,origin_r(2,1), origin_r(2,2), origin_r(2,3),'-(X-axis)','HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
hold(app.UIAxes3,'on');
quiver3(app.UIAxes3,origin_r(3,1), origin_r(3,2), origin_r(3,3), EigVec(1,2), EigVec(2,2), EigVec(3,2));
text(app.UIAxes3,origin_r(3,1) + EigVec(1,2), origin_r(3,2) + EigVec(2,2), origin_r(3,3) + EigVec(3,2), sprintf('\\sigma_p: %.2f N/mm^2', EigVal(2,2)));
text(app.UIAxes3,origin_r(3,1), origin_r(3,2), origin_r(3,3),'+(Y-axis)','HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
hold(app.UIAxes3,'on');
quiver3(app.UIAxes3,origin_r(4,1), origin_r(4,2), origin_r(4,3), -EigVec(1,2), -EigVec(2,2), -EigVec(3,2));
text(app.UIAxes3,origin_r(4,1) - EigVec(1,2), origin_r(4,2) - EigVec(2,2), origin_r(4,3) - EigVec(3,2), sprintf('\\sigma_p: %.2f N/mm^2', EigVal(2,2)));
text(app.UIAxes3,origin_r(4,1), origin_r(4,2), origin_r(4,3),'-(Y-axis)','HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
hold(app.UIAxes3,'on');
quiver3(app.UIAxes3,origin_r(5,1), origin_r(5,2), origin_r(5,3), EigVec(1,3), EigVec(2,3), EigVec(3,3));
text(app.UIAxes3,origin_r(5,1) + EigVec(1,3), origin_r(5,2) + EigVec(2,3), origin_r(5,3) + EigVec(3,3), sprintf('\\sigma_p: %.2f N/mm^2', EigVal(3,3)));
text(app.UIAxes3,origin_r(5,1), origin_r(5,2), origin_r(5,3),'+(Z-axis)','HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
hold(app.UIAxes3,'on');
quiver3(app.UIAxes3,origin_r(6,1), origin_r(6,2), origin_r(6,3), -EigVec(1,3), -EigVec(2,3), -EigVec(3,3));
text(app.UIAxes3,origin_r(6,1) - EigVec(1,3), origin_r(6,2) - EigVec(2,3), origin_r(6,3) - EigVec(3,3), sprintf('\\sigma_p: %.2f N/mm^2', EigVal(3,3)));
text(app.UIAxes3,origin_r(6,1), origin_r(6,2), origin_r(6,3),'-(Z-axis)','HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
patch(app.UIAxes3,'Vertices', r_v, 'Faces', faces, 'FaceColor', 'cyan', 'FaceAlpha',0.5,'EdgeColor', 'k');
axis(app.UIAxes3,'equal');
xlabel(app.UIAxes3,'X');
ylabel(app.UIAxes3,'Y');
zlabel(app.UIAxes3,'Z');
title(app.UIAxes3,'Element subjected to Principal Stresses only');
grid(app.UIAxes3,'on');
view(app.UIAxes3,3);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.stPrincipalStressEditField.Value = 0;
            app.EditField4.Value = 0;
            app.EditField4_2.Value = 0;
            app.EditField4_3.Value = 0;
            app.ndPrincipalStressEditField.Value = 0;
            app.EditField4_4.Value = 0;
            app.EditField4_5.Value = 0;
            app.EditField4_6.Value = 0;
            app.rdPrincipalStressEditField.Value = 0;
            app.EditField4_7.Value = 0;
            app.EditField4_8.Value = 0;
            app.EditField4_9.Value = 0;
            cla(app.UIAxes);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
        end

        % Button pushed function: ElementButton
        function ElementButtonPushed(app, event)
            sigma_xx = app.xxEditField.Value;
            sigma_xy = app.xyEditField.Value;
            sigma_xz = app.xzEditField.Value;
            sigma_yx = app.yxEditField.Value;
            sigma_yy = app.yyEditField.Value;
            sigma_yz = app.yzEditField.Value;
            sigma_zx = app.zxEditField.Value;
            sigma_zy = app.zyEditField.Value;
            sigma_zz = app.zzEditField.Value;


sigma = [sigma_xx, sigma_xy, sigma_xz; sigma_yx, sigma_yy, sigma_yz; sigma_zx, sigma_zy, sigma_zz];

% Define the 8 vertices of the cube
v = [
    0, 0, 0;
    1, 0, 0;
    1, 1, 0;
    0, 1, 0;
    0, 0, 1;
    1, 0, 1;
    1, 1, 1;
    0, 1, 1
];

% Define the faces of the cuboid (each face is a set of 4 vertex indices)
faces = [
    1, 2, 6, 5;
    2, 3, 7, 6;
    3, 4, 8, 7;
    4, 1, 5, 8;
    1, 2, 3, 4;
    5, 6, 7, 8
];


% Define the origin of each face (for plotting the stress values)
origin = [(v(2,1) + v(3,1) + v(7,1) + v(6,1))/4, (v(2,2) + v(3,2) + v(7,2) + v(6,2))/4, (v(2,3) + v(3,3) + v(7,3) + v(6,3))/4;
          (v(1,1) + v(4,1) + v(8,1) + v(5,1))/4, (v(1,2) + v(4,2) + v(8,2) + v(5,2))/4, (v(1,3) + v(4,3) + v(8,3) + v(5,3))/4;
          (v(4,1) + v(3,1) + v(7,1) + v(8,1))/4, (v(4,2) + v(3,2) + v(7,2) + v(8,2))/4, (v(4,3) + v(3,3) + v(7,3) + v(8,3))/4;
          (v(1,1) + v(2,1) + v(6,1) + v(5,1))/4, (v(1,2) + v(2,2) + v(6,2) + v(5,2))/4, (v(1,3) + v(2,3) + v(6,3) + v(5,3))/4;
          (v(5,1) + v(6,1) + v(7,1) + v(8,1))/4, (v(5,2) + v(6,2) + v(7,2) + v(8,2))/4, (v(5,3) + v(6,3) + v(7,3) + v(8,3))/4;
          (v(1,1) + v(2,1) + v(3,1) + v(4,1))/4, (v(1,2) + v(2,2) + v(3,2) + v(4,2))/4, (v(1,3) + v(2,3) + v(3,3) + v(4,3))/4];


%Ploting the original cube
quiver3(app.UIAxes2,origin(1,1), origin(1,2), origin(1,3), 1, 0, 0);
text(app.UIAxes2,origin(1,1), origin(1,2), origin(1,3),'+(X-axis)', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
text(app.UIAxes2,1 + origin(1,1), origin(1,2), origin(1,3), sprintf('\\sigma_{xx}: %.2f N/mm^2', sigma(1,1)));
hold(app.UIAxes2,'on');
quiver3(app.UIAxes2,origin(1,1), origin(1,2), origin(1,3), 0, 1, 0);
text(app.UIAxes2,origin(1,1), 1 + origin(1,2), origin(1,3), sprintf('\\tau_{xy}: %.2f N/mm^2', sigma(1,2)));
hold(app.UIAxes2,'on');
quiver3(app.UIAxes2,origin(1,1), origin(1,2), origin(1,3), 0, 0, 1);
text(app.UIAxes2,origin(1,1), origin(1,2), 1 + origin(1,3), sprintf('\\tau_{xz}: %.2f N/mm^2', sigma(1,3)));
hold(app.UIAxes2,'on');
text(app.UIAxes2,origin(2,1), origin(2,2), origin(2,3),'-(X-axis)','HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
quiver3(app.UIAxes2,origin(2,1), origin(2,2), origin(2,3), -1, 0, 0);
text(app.UIAxes2,-1 + origin(2,1), origin(2,2), origin(2,3), sprintf('\\sigma_{xx}: %.2f N/mm^2', sigma(1,1)));
hold(app.UIAxes2,'on');
quiver3(app.UIAxes2,origin(2,1), origin(2,2), origin(2,3), 0, -1, 0);
text(app.UIAxes2,origin(2,1),-1 + origin(2,2), origin(2,3), sprintf('\\tau_{xy}: %.2f N/mm^2', sigma(1,2)));
hold(app.UIAxes2,'on');
quiver3(app.UIAxes2,origin(2,1), origin(2,2), origin(2,3), 0, 0, -1);
text(app.UIAxes2,origin(2,1),origin(2,2),-1 + origin(2,3), sprintf('\\tau_{xz}: %.2f N/mm^2', sigma(1,3)));
hold(app.UIAxes2,'on');
text(app.UIAxes2,origin(3,1), origin(3,2), origin(3,3),'+(Y-axis)','HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
quiver3(app.UIAxes2,origin(3,1), origin(3,2), origin(3,3), 1, 0, 0);
text(app.UIAxes2,1 + origin(3,1), origin(3,2), origin(3,3), sprintf('\\tau_{yx}: %.2f N/mm^2', sigma(2,1)));
hold(app.UIAxes2,'on');
quiver3(app.UIAxes2,origin(3,1), origin(3,2), origin(3,3), 0, 1, 0);
text(app.UIAxes2,origin(3,1), 1 + origin(3,2), origin(3,3), sprintf('\\sigma_{yy}: %.2f N/mm^2', sigma(2,2)));
hold(app.UIAxes2,'on');
quiver3(app.UIAxes2,origin(3,1), origin(3,2), origin(3,3), 0, 0, 1);
text(app.UIAxes2,origin(3,1), origin(3,2), 1 + origin(3,3), sprintf('\\tau_{yz}: %.2f N/mm^2', sigma(2,3)));
hold(app.UIAxes2,'on');
text(app.UIAxes2,origin(4,1), origin(4,2), origin(4,3),'-(Y-axis)','HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
quiver3(app.UIAxes2,origin(4,1), origin(4,2), origin(4,3), -1, 0, 0);
text(app.UIAxes2,-1 + origin(4,1), origin(4,2), origin(4,3), sprintf('\\tau_{yx}: %.2f N/mm^2', sigma(2,1)));
hold(app.UIAxes2,'on');
quiver3(app.UIAxes2,origin(4,1), origin(4,2), origin(4,3), 0, -1, 0);
text(app.UIAxes2,origin(4,1), -1 + origin(4,2), origin(4,3), sprintf('\\sigma_{yy}: %.2f N/mm^2', sigma(2,2)));
hold(app.UIAxes2,'on');
quiver3(app.UIAxes2,origin(4,1), origin(4,2), origin(4,3), 0, 0, -1);
text(app.UIAxes2,origin(4,1), origin(4,2), -1 + origin(4,3), sprintf('\\tau_{yz}: %.2f N/mm^2', sigma(2,3)));
hold(app.UIAxes2,'on');
text(app.UIAxes2,origin(5,1), origin(5,2), origin(5,3),'+(Z-axis)','HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
quiver3(app.UIAxes2,origin(5,1), origin(5,2), origin(5,3), 1, 0, 0);
text(app.UIAxes2,1 + origin(5,1), origin(5,2), origin(5,3), sprintf('\\tau_{zx}: %.2f N/mm^2', sigma(3,1)));
hold(app.UIAxes2,'on');
quiver3(app.UIAxes2,origin(5,1), origin(5,2), origin(5,3), 0, 1, 0);
text(app.UIAxes2,origin(5,1), 1 + origin(5,2), origin(5,3), sprintf('\\tau_{zy}: %.2f N/mm^2', sigma(3,2)));
hold(app.UIAxes2,'on');
quiver3(app.UIAxes2,origin(5,1), origin(5,2), origin(5,3), 0, 0, 1);
text(app.UIAxes2,origin(5,1), origin(5,2), 1 + origin(5,3), sprintf('\\sigma_{zz}: %.2f N/mm^2', sigma(3,3)));
hold(app.UIAxes2,'on');
text(app.UIAxes2,origin(6,1), origin(6,2), origin(6,3),'-(Z-axis)','HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
quiver3(app.UIAxes2,origin(6,1), origin(6,2), origin(6,3), -1, 0, 0);
text(app.UIAxes2,-1 + origin(6,1), origin(6,2), origin(6,3), sprintf('\\tau_{zx}: %.2f N/mm^2', sigma(3,1)));
hold(app.UIAxes2,'on');
quiver3(app.UIAxes2,origin(6,1), origin(6,2), origin(6,3), 0, -1, 0);
text(app.UIAxes2,origin(6,1), -1 + origin(6,2), origin(6,3), sprintf('\\tau_{zy}: %.2f N/mm^2', sigma(3,2)));
hold(app.UIAxes2,'on');
quiver3(app.UIAxes2,origin(6,1), origin(6,2), origin(6,3), 0, 0, -1);
text(app.UIAxes2,origin(6,1), origin(6,2), -1 + origin(6,3), sprintf('\\sigma_{zz}: %.2f N/mm^2', sigma(3,3)));
patch(app.UIAxes2,'Vertices', v, 'Faces', faces, 'FaceColor', 'r','FaceAlpha',0.5, 'EdgeColor', 'k');
axis(app.UIAxes2,'equal');
xlabel(app.UIAxes2,'X');
ylabel(app.UIAxes2,'Y');
zlabel(app.UIAxes2,'Z');
title(app.UIAxes2,'Element subjected to Normal & Shear Stresses');
grid(app.UIAxes2,'on');
view(app.UIAxes2,3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Element rotating to Principal Plane')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Element subjected to Normal & Shear Stresses')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Element subjected to Principal Stresses only')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.StressTensorinNmm2Panel = uipanel(app.UIFigure);
            app.StressTensorinNmm2Panel.Title = 'Stress Tensor ( in N/mm^2)';
            app.Label = uilabel(app.StressTensorinNmm2Panel);
            app.Label.Text = 'σxx';
            app.xxEditField = uieditfield(app.StressTensorinNmm2Panel, 'numeric');
            app.xyEditFieldLabel = uilabel(app.StressTensorinNmm2Panel);
            app.xyEditFieldLabel.Text = 'σxy';
            app.xyEditField = uieditfield(app.StressTensorinNmm2Panel, 'numeric');
            app.xzEditFieldLabel = uilabel(app.StressTensorinNmm2Panel);
            app.xzEditFieldLabel.Text = 'σxz';
            app.xzEditField = uieditfield(app.StressTensorinNmm2Panel, 'numeric');
            app.yxEditFieldLabel = uilabel(app.StressTensorinNmm2Panel);
            app.yxEditFieldLabel.Text = 'σyx';
            app.yxEditField = uieditfield(app.StressTensorinNmm2Panel, 'numeric');
            app.yyEditFieldLabel = uilabel(app.StressTensorinNmm2Panel);
            app.yyEditFieldLabel.Text = 'σyy';
            app.yyEditField = uieditfield(app.StressTensorinNmm2Panel, 'numeric');
            app.yzEditFieldLabel = uilabel(app.StressTensorinNmm2Panel);
            app.yzEditFieldLabel.Text = 'σyz';
            app.yzEditField = uieditfield(app.StressTensorinNmm2Panel, 'numeric');
            app.zxEditFieldLabel = uilabel(app.StressTensorinNmm2Panel);
            app.zxEditFieldLabel.Text = 'σzx';
            app.zxEditField = uieditfield(app.StressTensorinNmm2Panel, 'numeric');
            app.zyEditFieldLabel = uilabel(app.StressTensorinNmm2Panel);
            app.zyEditFieldLabel.Text = 'σzy';
            app.zyEditField = uieditfield(app.StressTensorinNmm2Panel, 'numeric');
            app.zzEditFieldLabel = uilabel(app.StressTensorinNmm2Panel);
            app.zzEditFieldLabel.Text = 'σzz';
            app.zzEditField = uieditfield(app.StressTensorinNmm2Panel, 'numeric');
            app.OutputsPanel = uipanel(app.UIFigure);
            app.OutputsPanel.Title = 'Outputs';
            app.stPrincipalStressNmm2anditsDirectionPanel = uipanel(app.OutputsPanel);
            app.stPrincipalStressNmm2anditsDirectionPanel.Title = ' 1st Principal Stress (N/mm^2) and its Direction';
            app.stPrincipalStressEditFieldLabel = uilabel(app.stPrincipalStressNmm2anditsDirectionPanel);
            app.stPrincipalStressEditFieldLabel.Text = '1st Principal Stress';
            app.stPrincipalStressEditField = uieditfield(app.stPrincipalStressNmm2anditsDirectionPanel, 'numeric');
            app.EditField4 = uieditfield(app.stPrincipalStressNmm2anditsDirectionPanel, 'numeric');
            app.EditField4_2 = uieditfield(app.stPrincipalStressNmm2anditsDirectionPanel, 'numeric');
            app.EditField4_3 = uieditfield(app.stPrincipalStressNmm2anditsDirectionPanel, 'numeric');
            app.DirectionLabel = uilabel(app.stPrincipalStressNmm2anditsDirectionPanel);
            app.DirectionLabel.Text = 'Direction    =';
            app.ndPrincipalStressNmm2anditsDirectionPanel = uipanel(app.OutputsPanel);
            app.ndPrincipalStressNmm2anditsDirectionPanel.Title = ' 2nd Principal Stress (N/mm^2) and its Direction';
            app.ndPrincipalStressEditFieldLabel = uilabel(app.ndPrincipalStressNmm2anditsDirectionPanel);
            app.ndPrincipalStressEditFieldLabel.Text = '2nd Principal Stress';
            app.ndPrincipalStressEditField = uieditfield(app.ndPrincipalStressNmm2anditsDirectionPanel, 'numeric');
            app.EditField4_4 = uieditfield(app.ndPrincipalStressNmm2anditsDirectionPanel, 'numeric');
            app.EditField4_5 = uieditfield(app.ndPrincipalStressNmm2anditsDirectionPanel, 'numeric');
            app.EditField4_6 = uieditfield(app.ndPrincipalStressNmm2anditsDirectionPanel, 'numeric');
            app.DirectionLabel_2 = uilabel(app.ndPrincipalStressNmm2anditsDirectionPanel);
            app.DirectionLabel_2.Text = 'Direction    =';
            app.rdPrincipalStressNmm2anditsDirectionPanel = uipanel(app.OutputsPanel);
            app.rdPrincipalStressNmm2anditsDirectionPanel.Title = ' 3rd Principal Stress (N/mm^2) and its Direction';
            app.rdPrincipalStressEditFieldLabel = uilabel(app.rdPrincipalStressNmm2anditsDirectionPanel);
            app.rdPrincipalStressEditFieldLabel.Text = '3rd Principal Stress';
            app.rdPrincipalStressEditField = uieditfield(app.rdPrincipalStressNmm2anditsDirectionPanel, 'numeric');
            app.EditField4_7 = uieditfield(app.rdPrincipalStressNmm2anditsDirectionPanel, 'numeric');
            app.EditField4_8 = uieditfield(app.rdPrincipalStressNmm2anditsDirectionPanel, 'numeric');
            app.EditField4_9 = uieditfield(app.rdPrincipalStressNmm2anditsDirectionPanel, 'numeric');
            app.DirectionLabel_3 = uilabel(app.rdPrincipalStressNmm2anditsDirectionPanel);
            app.DirectionLabel_3.Text = 'Direction    =';
            app.INTERNLabel = uilabel(app.UIFigure);
            app.INTERNLabel.Text = {'INTERNATIONAL INSTITUTE OF INFORMATION TECHNOLOGY, HYDERABAD'; 'EARTHQUAKE ENGINEERING RESEARCH CENTRE'; 'PROJECT'; 'THEORY OF ELASTICITY (CE 0.501)'};
            app.INTERNLabel_2 = uilabel(app.UIFigure);
            app.INTERNLabel_2.Text = 'PRINCIPAL STRESS AND PRINCIPAL PLANE';
            app.INTERNLabel_3 = uilabel(app.UIFigure);
            app.INTERNLabel_3.Text = 'Presented by : Jayant Lodhi (2024210009) & Chedapangu Seema (2024210008)';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.AnalysisButton = uibutton(app.UIFigure, 'push');
            app.AnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisButtonPushed, true);
            app.AnalysisButton.Text = 'Analysis';
            app.ElementButton = uibutton(app.UIFigure, 'push');
            app.ElementButton.ButtonPushedFcn = createCallbackFcn(app, @ElementButtonPushed, true);
            app.ElementButton.Text = 'Element';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Principal_Stress_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end